classdef run < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NameLabel                       matlab.ui.control.Label
        Name                            matlab.ui.control.EditField
        FlybyPlanetsEditFieldLabel      matlab.ui.control.Label
        FlybyPlanets                    matlab.ui.control.EditField
        MinMaxFlybysEditField_2Label    matlab.ui.control.Label
        MinMaxFlybys                    matlab.ui.control.EditField
        MinMaxRevolutionsEditField_2Label  matlab.ui.control.Label
        MinMaxRevolutions               matlab.ui.control.EditField
        TypeLabel                       matlab.ui.control.Label
        Type                            matlab.ui.control.DropDown
        ExcessVelocityEditFieldLabel    matlab.ui.control.Label
        ExcessVelocity                  matlab.ui.control.NumericEditField
        MinMaxTransferTimeEditFieldLabel  matlab.ui.control.Label
        MinMaxTransferTime              matlab.ui.control.EditField
        MinMaxLaunchDateEditFieldLabel  matlab.ui.control.Label
        MinMaxLaunchDate                matlab.ui.control.EditField
        InitialFilenameEditFieldLabel   matlab.ui.control.Label
        InitialFilename                 matlab.ui.control.EditField
        OutputFilenameEditFieldLabel    matlab.ui.control.Label
        OutputFilename                  matlab.ui.control.EditField
        PlotDropDownLabel               matlab.ui.control.Label
        Plot                            matlab.ui.control.DropDown
        UseParallelDropDownLabel        matlab.ui.control.Label
        UseParallel                     matlab.ui.control.DropDown
        MaxGenerationsEditFieldLabel    matlab.ui.control.Label
        MaxGenerations                  matlab.ui.control.NumericEditField
        PopulationSizeEditFieldLabel    matlab.ui.control.Label
        PopulationSize                  matlab.ui.control.NumericEditField
        SpiceDirectoryEditFieldLabel    matlab.ui.control.Label
        SpiceDirectory                  matlab.ui.control.EditField
        DoneButton                      matlab.ui.control.Button
        DeparturePlanetLabel            matlab.ui.control.Label
        DeparturePlanet                 matlab.ui.control.EditField
        DeparturePlanetLabel_2          matlab.ui.control.Label
        ArrivalPlanet                   matlab.ui.control.EditField
        MinFlybyAltitudeLabel           matlab.ui.control.Label
        MinFlybyAltitude                matlab.ui.control.NumericEditField
        NameLabel_2                     matlab.ui.control.Label
    end


    methods (Access = private)

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            input.problem_name  = app.Name.Value; % Problem name
            input.problem_type  = app.Type.Value; % Type of mission: condition at arrival planet (flyby/rendezvous)
            input.planet_dep    = app.DeparturePlanet.Value; % Departure planet using space nomenclature (e.g. 3==Earth)
            input.planet_arr    = app.ArrivalPlanet.Value;  % Arrival planet using space nomenclature (e.g. 5==Jupiter)
            input.vinf0_max     = app.ExcessVelocity.Value; % Hyperbolic excess velocity at departure planet (km/s)
            input.planet_fb     = strsplit(app.FlybyPlanets.Value, ','); % List of available planets to flyby in spice nomenclature
            input.rfb_min       = app.MinFlybyAltitude.Value; % minimum flyby altitude (km)
            input.n_fb          = str2num(app.MinMaxFlybys.Value); % minimum/maximum number of possible flybys
            input.rev           = str2num(app.MinMaxRevolutions.Value); % minimum/maximum number of possible revolutions
            input.ToF           = str2num(app.MinMaxTransferTime.Value); % minimum/maximum transfer time per leg (days)
            input.Initial_Date  = strsplit(app.MinMaxLaunchDate.Value, ','); % minimum/maximum Launch date (Gregorian Date)
            input.init_file     = str2num(app.InitialFilename.Value);      % Init population File name (if not provided, random initial population)
            input.output_file   = app.OutputFilename.Value; % Solution population File name
            input.plot          = str2num(app.Plot.Value);       % plotting option (recomended = 0, option =1 is under development)
            input.useParallel   = app.UseParallel.Value;   % yes/no for parallel execution of the genetic algorithm
            input.maxGen        = app.MaxGenerations.Value;     % maximum number of generations
            input.popsize       = app.PopulationSize.Value;     % Population Size
            input.spice_dir     = app.SpiceDirectory.Value; % The spice directory folder
            closereq
            %save('input.mat');
            molto_it(input);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NameLabel = uilabel(app.UIFigure);
            app.NameLabel.Text = 'Name';
            app.Name = uieditfield(app.UIFigure, 'text');
            app.Name.Value = 'example';
            app.FlybyPlanetsEditFieldLabel = uilabel(app.UIFigure);
            app.FlybyPlanetsEditFieldLabel.Text = 'Flyby Planets';
            app.FlybyPlanets = uieditfield(app.UIFigure, 'text');
            app.FlybyPlanets.Value = '4,3,2';
            app.MinMaxFlybysEditField_2Label = uilabel(app.UIFigure);
            app.MinMaxFlybysEditField_2Label.Text = 'Min/Max Flybys';
            app.MinMaxFlybys = uieditfield(app.UIFigure, 'text');
            app.MinMaxFlybys.Value = '[0,3]';
            app.MinMaxRevolutionsEditField_2Label = uilabel(app.UIFigure);
            app.MinMaxRevolutionsEditField_2Label.Text = 'Min/Max Revolutions';
            app.MinMaxRevolutions = uieditfield(app.UIFigure, 'text');
            app.MinMaxRevolutions.Value = '[0,0]';
            app.TypeLabel = uilabel(app.UIFigure);
            app.TypeLabel.Text = 'Type';
            app.Type = uidropdown(app.UIFigure);
            app.Type.Items = {'flyby', 'rendevous'};
            app.Type.Value = 'flyby';
            app.ExcessVelocityEditFieldLabel = uilabel(app.UIFigure);
            app.ExcessVelocityEditFieldLabel.Text = 'Excess Velocity';
            app.ExcessVelocity = uieditfield(app.UIFigure, 'numeric');
            app.MinMaxTransferTimeEditFieldLabel = uilabel(app.UIFigure);
            app.MinMaxTransferTimeEditFieldLabel.Text = 'Min/Max Transfer Time';
            app.MinMaxTransferTime = uieditfield(app.UIFigure, 'text');
            app.MinMaxTransferTime.Value = '[50 50 50 50; 500 500 500 1000]';
            app.MinMaxLaunchDateEditFieldLabel = uilabel(app.UIFigure);
            app.MinMaxLaunchDateEditFieldLabel.Text = 'Min/Max Launch Date';
            app.MinMaxLaunchDate = uieditfield(app.UIFigure, 'text');
            app.MinMaxLaunchDate.Value = '2029 Jan 01 00:00:00, 2030 Dec 31 00:00:00';
            app.InitialFilenameEditFieldLabel = uilabel(app.UIFigure);
            app.InitialFilenameEditFieldLabel.Text = 'Initial Filename';
            app.InitialFilename = uieditfield(app.UIFigure, 'text');
            app.InitialFilename.Value = 'example.txt';
            app.OutputFilenameEditFieldLabel = uilabel(app.UIFigure);
            app.OutputFilenameEditFieldLabel.Text = 'Output Filename';
            app.OutputFilename = uieditfield(app.UIFigure, 'text');
            app.OutputFilename.Value = 'example.txt';
            app.PlotDropDownLabel = uilabel(app.UIFigure);
            app.PlotDropDownLabel.Text = 'Plot';
            app.Plot = uidropdown(app.UIFigure);
            app.Plot.Items = {'0', '1'};
            app.Plot.Value = '0';
            app.UseParallelDropDownLabel = uilabel(app.UIFigure);
            app.UseParallelDropDownLabel.Text = 'Use Parallel';
            app.UseParallel = uidropdown(app.UIFigure);
            app.UseParallel.Items = {'yes', 'no'};
            app.UseParallel.Value = 'yes';
            app.MaxGenerationsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxGenerationsEditFieldLabel.Text = 'Max Generations';
            app.MaxGenerations = uieditfield(app.UIFigure, 'numeric');
            app.PopulationSizeEditFieldLabel = uilabel(app.UIFigure);
            app.PopulationSizeEditFieldLabel.Text = 'Population Size';
            app.PopulationSize = uieditfield(app.UIFigure, 'numeric');
            app.SpiceDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.SpiceDirectoryEditFieldLabel.Text = 'Spice Directory';
            app.SpiceDirectory = uieditfield(app.UIFigure, 'text');
            app.SpiceDirectory.Value = '/home/MOLTO-IT/spice';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.DeparturePlanetLabel = uilabel(app.UIFigure);
            app.DeparturePlanetLabel.Text = 'Departure Planet';
            app.DeparturePlanet = uieditfield(app.UIFigure, 'text');
            app.DeparturePlanet.Value = '3';
            app.DeparturePlanetLabel_2 = uilabel(app.UIFigure);
            app.DeparturePlanetLabel_2.Text = 'Departure Planet';
            app.ArrivalPlanet = uieditfield(app.UIFigure, 'text');
            app.ArrivalPlanet.Value = '5';
            app.MinFlybyAltitudeLabel = uilabel(app.UIFigure);
            app.MinFlybyAltitudeLabel.Text = 'Min Flyby Altitude';
            app.MinFlybyAltitude = uieditfield(app.UIFigure, 'numeric');
            app.NameLabel_2 = uilabel(app.UIFigure);
            app.NameLabel_2.Text = 'The format of each input is shown in the respective input field. Click on each input field to edit it. ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = run

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end